classdef excel_read < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        excelLabel          matlab.ui.control.Label
        excelPanel          matlab.ui.container.Panel
        xlsxEditFieldLabel  matlab.ui.control.Label
        xlsxEditField       matlab.ui.control.EditField
        UILabel             matlab.ui.control.Label
        Button_4            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        Label               matlab.ui.control.Label
        EditField           matlab.ui.control.EditField
        Label_2             matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        Button_2            matlab.ui.control.Button
        EditField_2Label    matlab.ui.control.Label
        EditField_2         matlab.ui.control.EditField
        excelButton         matlab.ui.control.Button
        xlsxLabel           matlab.ui.control.Label
        VarNLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global T
            T = readtable(app.xlsxEditField.Value,"ReadVariableNames",false,"ReadRowNames",false);%默认读取数据
%             disp(T)
%             T1 = sortrows(T,'D');
%             disp(T1)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            global T
            [file,path] = uigetfile(['*.xls','*.xlsx']);
            T = readtable(fullfile(path,file),"ReadVariableNames",false,"ReadRowNames",false);%默认读取数据
%             T = readtable(fullfile(path,file));
            disp(T)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global T
            global T1
            disp(app.DropDown.Value)
            if(app.DropDown.Value == "ascend") %选择升序排列
                disp('Yes')
                T1 = sortrows(T,app.EditField.Value,"ascend");
            end
            if(app.DropDown.Value == "descend")%选择降序排列
                disp('Yes')
                T1 = sortrows(T,app.EditField.Value,'descend');
            end
            disp(T1)
        end

        % Button pushed function: excelButton
        function excelButtonPushed(app, event)
            global T1;
            writetable(T1,app.EditField_2.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.excelLabel = uilabel(app.UIFigure);
            app.excelLabel.Text = 'excel读取数据并排序小程序';
            app.excelPanel = uipanel(app.UIFigure);
            app.excelPanel.Title = '载入excel数据';
            app.xlsxEditFieldLabel = uilabel(app.excelPanel);
            app.xlsxEditFieldLabel.Text = '方式一：输入文件名（*。xlsx）';
            app.xlsxEditField = uieditfield(app.excelPanel, 'text');
            app.UILabel = uilabel(app.excelPanel);
            app.UILabel.Text = '方式二：UI选择文件进行读取';
            app.Button_4 = uibutton(app.excelPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '选择文件';
            app.Button_3 = uibutton(app.excelPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '载入';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '排序并输出';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '排序的列名称';
            app.EditField = uieditfield(app.Panel, 'text');
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '升序、降序';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'ascend', 'descend'};
            app.DropDown.Value = 'ascend';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '排序';
            app.EditField_2Label = uilabel(app.Panel);
            app.EditField_2Label.Text = '输出文件名';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.excelButton = uibutton(app.Panel, 'push');
            app.excelButton.ButtonPushedFcn = createCallbackFcn(app, @excelButtonPushed, true);
            app.excelButton.Text = '导出排序结果（excel）';
            app.xlsxLabel = uilabel(app.Panel);
            app.xlsxLabel.Text = '全名:*.xlsx';
            app.VarNLabel = uilabel(app.Panel);
            app.VarNLabel.Text = 'VarN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = excel_read

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end