classdef pca_program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        excelButton  matlab.ui.control.Button
        PCALabel     matlab.ui.control.Label
        Label        matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Button       matlab.ui.control.Button
        UITable      matlab.ui.control.Table
        UITable_2    matlab.ui.control.Table
        ALabel       matlab.ui.control.Label
        CoeffLabel   matlab.ui.control.Label
        UITable_3    matlab.ui.control.Table
        Label_3      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: excelButton
        function excelButtonPushed(app, event)
            [file,path] = uigetfile();
            global T;%用于存储表数据
            global A;%用于存储从excel中读取到的数据
            T = readtable(fullfile(path,file),'ReadVariableNames',false,'ReadRowNames',true);
            A = table2array(T);
            app.UITable.Data = A;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global A;
            [coeff,score,latent,tsquared,explained,mu] = pca(A)
            app.UITable_2.Data = coeff;
            app.UITable_3.Data = explained;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.excelButton = uibutton(app.UIFigure, 'push');
            app.excelButton.ButtonPushedFcn = createCallbackFcn(app, @excelButtonPushed, true);
            app.excelButton.Text = '载入excel数据';
            app.PCALabel = uilabel(app.UIFigure);
            app.PCALabel.Text = 'PCA（主成分分析小程序）';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '第一步';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '第二步';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '主成分分析';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = '原始数据矩阵A';
            app.CoeffLabel = uilabel(app.UIFigure);
            app.CoeffLabel.Text = '系数矩阵Coeff';
            app.UITable_3 = uitable(app.UIFigure);
            app.UITable_3.ColumnName = {'Column 1'};
            app.UITable_3.RowName = {};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '贡献比%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pca_program

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end